classdef Reversi_UI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button_1   matlab.ui.control.StateButton
        Button_2   matlab.ui.control.StateButton
        Button_3   matlab.ui.control.StateButton
        Button_4   matlab.ui.control.StateButton
        Button_8   matlab.ui.control.StateButton
        Button_7   matlab.ui.control.StateButton
        Button_6   matlab.ui.control.StateButton
        Button_5   matlab.ui.control.StateButton
        Button_16  matlab.ui.control.StateButton
        Button_15  matlab.ui.control.StateButton
        Button_14  matlab.ui.control.StateButton
        Button_13  matlab.ui.control.StateButton
        Button_12  matlab.ui.control.StateButton
        Button_11  matlab.ui.control.StateButton
        Button_10  matlab.ui.control.StateButton
        Button_9   matlab.ui.control.StateButton
        Button_32  matlab.ui.control.StateButton
        Button_31  matlab.ui.control.StateButton
        Button_30  matlab.ui.control.StateButton
        Button_29  matlab.ui.control.StateButton
        Button_28  matlab.ui.control.StateButton
        Button_27  matlab.ui.control.StateButton
        Button_26  matlab.ui.control.StateButton
        Button_24  matlab.ui.control.StateButton
        Button_23  matlab.ui.control.StateButton
        Button_22  matlab.ui.control.StateButton
        Button_21  matlab.ui.control.StateButton
        Button_20  matlab.ui.control.StateButton
        Button_19  matlab.ui.control.StateButton
        Button_18  matlab.ui.control.StateButton
        Button_25  matlab.ui.control.StateButton
        Button_17  matlab.ui.control.StateButton
        Button_33  matlab.ui.control.StateButton
        Button_34  matlab.ui.control.StateButton
        Button_41  matlab.ui.control.StateButton
        Button_42  matlab.ui.control.StateButton
        Button_35  matlab.ui.control.StateButton
        Button_43  matlab.ui.control.StateButton
        Button_49  matlab.ui.control.StateButton
        Button_50  matlab.ui.control.StateButton
        Button_51  matlab.ui.control.StateButton
        Button_36  matlab.ui.control.StateButton
        Button_44  matlab.ui.control.StateButton
        Button_52  matlab.ui.control.StateButton
        Button_37  matlab.ui.control.StateButton
        Button_45  matlab.ui.control.StateButton
        Button_53  matlab.ui.control.StateButton
        Button_57  matlab.ui.control.StateButton
        Button_58  matlab.ui.control.StateButton
        Button_59  matlab.ui.control.StateButton
        Button_60  matlab.ui.control.StateButton
        Button_61  matlab.ui.control.StateButton
        Button_38  matlab.ui.control.StateButton
        Button_46  matlab.ui.control.StateButton
        Button_54  matlab.ui.control.StateButton
        Button_62  matlab.ui.control.StateButton
        Button_39  matlab.ui.control.StateButton
        Button_47  matlab.ui.control.StateButton
        Button_55  matlab.ui.control.StateButton
        Button_63  matlab.ui.control.StateButton
        Button_40  matlab.ui.control.StateButton
        Button_48  matlab.ui.control.StateButton
        Button_56  matlab.ui.control.StateButton
        Button_64  matlab.ui.control.StateButton
        Button_65  matlab.ui.control.Button
    end


    methods (Access = private)
    
        function drawBoard(app, B)
            board = B.BoardState';
            for i = 1 : 64
                if board(i) == 1
                    eval(strcat('app.Button_',num2str(i),'.BackgroundColor = [0,0,0];'));
                    eval(strcat('app.Button_',num2str(i),'.Enable = false;'));
                elseif board(i) == -1
                    eval(strcat('app.Button_',num2str(i),'.BackgroundColor = [1,1,1]'));
                    eval(strcat('app.Button_',num2str(i),'.Enable = false;'));
                else
                    eval(strcat('app.Button_',num2str(i),'.BackgroundColor = [0.94,0.94,0.94];'));
                    eval(strcat('app.Button_',num2str(i),'.Enable = true;'));
                end
            end
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            load('BoardStates.mat');
        end

        % Button pushed function: Button_65
        function Button_65Pushed(app, event)
            B = Board();
            B.Initialize();
            noPossibleMoveInARow = 0; % if >=2, P1 and P2 has no place to play. => end of the game
            app.drawBoard(B);
            if B.isPlayer1Turn
            while noPossibleMoveInARow < 2
                %% Search Possible Moves
                [possiblePositions] = findPossibleMoves(B);
                numPossMove = size(possiblePositions,1);
                if numPossMove == 0 % No Possible Moves
                    noPossibleMoveInARow = noPossibleMoveInARow + 1;
                    B.isPlayer1Turn = not(B.isPlayer1Turn);
                    continue
                else
                   %% Select Move
                    noPossibleMoveInARow = 0;
                    if goRandom % Next move is randomly selected
                        selectedMoveIndex = randi(numPossMove);
                    else % Next move is selected based on softmax function
                       %% RL parameters
                        % reward : immediate board score after the move
                        % value : expected game result from BoardStates
                        rewardMoves = zeros(numPossMove,1);
                        valueMoves = zeros(numPossMove,1);
        
                       %% Check all possible moves
                        for m = 1 : numPossMove
                            tempB = updateBoard(B, possiblePositions(m,:));
                            tempb = tempB.BoardState;
                          %% Calculate reward
                            rewardMoves(m) = sum(sum(tempb));
                          %% Calculate value
                            stateFound = false;
                            i = 1;
                            currentStageNumber = sum(sum(tempb == 0)) + 1;
                            while and(BoardStates{currentStageNumber}{i,3} ~=0, ~stateFound)
                                if tempb == BoardStates{currentStageNumber}{i,1}
                                    valueMoves(m) = BoardStates{currentStageNumber}{i,2} / BoardStates{currentStageNumber}{i,3};
                                    stateFound = true;
                                end
                                i = i + 1;
                            end
                        end
                      %% Evaluate score
                        % scoreMoves : total score from the reward and value
                        scoreMoves = rewardMoves*rewardValueRatio + valueMoves*(1-rewardValueRatio); 
                      %% Select Move
                        if B.isPlayer1Turn
                            selectedMoveIndex = softmaxSel(scoreMoves, inverseTemperature);
                        else
                            selectedMoveIndex = softmaxSel(scoreMoves * (-1), inverseTemperature);
                        end
                    end
                  %% Update the board 
                    newB = updateBoard(B, possiblePositions(selectedMoveIndex, :));
                    Moves = [Moves, {newB}];
                    B = newB;
                end
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.Button_1 = uibutton(app.UIFigure, 'state');
            app.Button_1.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'state');
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.UIFigure, 'state');
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.UIFigure, 'state');
            app.Button_4.Text = '';
            app.Button_8 = uibutton(app.UIFigure, 'state');
            app.Button_8.Text = '';
            app.Button_7 = uibutton(app.UIFigure, 'state');
            app.Button_7.Text = '';
            app.Button_6 = uibutton(app.UIFigure, 'state');
            app.Button_6.Text = '';
            app.Button_5 = uibutton(app.UIFigure, 'state');
            app.Button_5.Text = '';
            app.Button_16 = uibutton(app.UIFigure, 'state');
            app.Button_16.Text = '';
            app.Button_15 = uibutton(app.UIFigure, 'state');
            app.Button_15.Text = '';
            app.Button_14 = uibutton(app.UIFigure, 'state');
            app.Button_14.Text = '';
            app.Button_13 = uibutton(app.UIFigure, 'state');
            app.Button_13.Text = '';
            app.Button_12 = uibutton(app.UIFigure, 'state');
            app.Button_12.Text = '';
            app.Button_11 = uibutton(app.UIFigure, 'state');
            app.Button_11.Text = '';
            app.Button_10 = uibutton(app.UIFigure, 'state');
            app.Button_10.Text = '';
            app.Button_9 = uibutton(app.UIFigure, 'state');
            app.Button_9.Text = '';
            app.Button_32 = uibutton(app.UIFigure, 'state');
            app.Button_32.Text = '';
            app.Button_31 = uibutton(app.UIFigure, 'state');
            app.Button_31.Text = '';
            app.Button_30 = uibutton(app.UIFigure, 'state');
            app.Button_30.Text = '';
            app.Button_29 = uibutton(app.UIFigure, 'state');
            app.Button_29.Text = '';
            app.Button_28 = uibutton(app.UIFigure, 'state');
            app.Button_28.Text = '';
            app.Button_27 = uibutton(app.UIFigure, 'state');
            app.Button_27.Text = '';
            app.Button_26 = uibutton(app.UIFigure, 'state');
            app.Button_26.Text = '';
            app.Button_24 = uibutton(app.UIFigure, 'state');
            app.Button_24.Text = '';
            app.Button_23 = uibutton(app.UIFigure, 'state');
            app.Button_23.Text = '';
            app.Button_22 = uibutton(app.UIFigure, 'state');
            app.Button_22.Text = '';
            app.Button_21 = uibutton(app.UIFigure, 'state');
            app.Button_21.Text = '';
            app.Button_20 = uibutton(app.UIFigure, 'state');
            app.Button_20.Text = '';
            app.Button_19 = uibutton(app.UIFigure, 'state');
            app.Button_19.Text = '';
            app.Button_18 = uibutton(app.UIFigure, 'state');
            app.Button_18.Text = '';
            app.Button_25 = uibutton(app.UIFigure, 'state');
            app.Button_25.Text = '';
            app.Button_17 = uibutton(app.UIFigure, 'state');
            app.Button_17.Text = '';
            app.Button_33 = uibutton(app.UIFigure, 'state');
            app.Button_33.Text = '';
            app.Button_34 = uibutton(app.UIFigure, 'state');
            app.Button_34.Text = '';
            app.Button_41 = uibutton(app.UIFigure, 'state');
            app.Button_41.Text = '';
            app.Button_42 = uibutton(app.UIFigure, 'state');
            app.Button_42.Text = '';
            app.Button_35 = uibutton(app.UIFigure, 'state');
            app.Button_35.Text = '';
            app.Button_43 = uibutton(app.UIFigure, 'state');
            app.Button_43.Text = '';
            app.Button_49 = uibutton(app.UIFigure, 'state');
            app.Button_49.Text = '';
            app.Button_50 = uibutton(app.UIFigure, 'state');
            app.Button_50.Text = '';
            app.Button_51 = uibutton(app.UIFigure, 'state');
            app.Button_51.Text = '';
            app.Button_36 = uibutton(app.UIFigure, 'state');
            app.Button_36.Text = '';
            app.Button_44 = uibutton(app.UIFigure, 'state');
            app.Button_44.Text = '';
            app.Button_52 = uibutton(app.UIFigure, 'state');
            app.Button_52.Text = '';
            app.Button_37 = uibutton(app.UIFigure, 'state');
            app.Button_37.Text = '';
            app.Button_45 = uibutton(app.UIFigure, 'state');
            app.Button_45.Text = '';
            app.Button_53 = uibutton(app.UIFigure, 'state');
            app.Button_53.Text = '';
            app.Button_57 = uibutton(app.UIFigure, 'state');
            app.Button_57.Text = '';
            app.Button_58 = uibutton(app.UIFigure, 'state');
            app.Button_58.Text = '';
            app.Button_59 = uibutton(app.UIFigure, 'state');
            app.Button_59.Text = '';
            app.Button_60 = uibutton(app.UIFigure, 'state');
            app.Button_60.Text = '';
            app.Button_61 = uibutton(app.UIFigure, 'state');
            app.Button_61.Text = '';
            app.Button_38 = uibutton(app.UIFigure, 'state');
            app.Button_38.Text = '';
            app.Button_46 = uibutton(app.UIFigure, 'state');
            app.Button_46.Text = '';
            app.Button_54 = uibutton(app.UIFigure, 'state');
            app.Button_54.Text = '';
            app.Button_62 = uibutton(app.UIFigure, 'state');
            app.Button_62.Text = '';
            app.Button_39 = uibutton(app.UIFigure, 'state');
            app.Button_39.Text = '';
            app.Button_47 = uibutton(app.UIFigure, 'state');
            app.Button_47.Text = '';
            app.Button_55 = uibutton(app.UIFigure, 'state');
            app.Button_55.Text = '';
            app.Button_63 = uibutton(app.UIFigure, 'state');
            app.Button_63.Text = '';
            app.Button_40 = uibutton(app.UIFigure, 'state');
            app.Button_40.Text = '';
            app.Button_48 = uibutton(app.UIFigure, 'state');
            app.Button_48.Text = '';
            app.Button_56 = uibutton(app.UIFigure, 'state');
            app.Button_56.Text = '';
            app.Button_64 = uibutton(app.UIFigure, 'state');
            app.Button_64.Text = '';
            app.Button_65 = uibutton(app.UIFigure, 'push');
            app.Button_65.ButtonPushedFcn = createCallbackFcn(app, @Button_65Pushed, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = Reversi_UI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end